classdef CollectDP_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DMDControlPanel                 matlab.ui.container.Panel
        DMDstateButton                  matlab.ui.control.StateButton
        pushMaskToDMDButton             matlab.ui.control.Button
        MaskFilenameLabel               matlab.ui.control.Label
        WrittenMaskFilenameEditField    matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        WorkingDirectoryEditField       matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        CollectDPTab                    matlab.ui.container.Tab
        SEMImagePanel                   matlab.ui.container.Panel
        openImage                       matlab.ui.control.Button
        ImageFilenameLabel              matlab.ui.control.Label
        textImagefilenameField          matlab.ui.control.EditField
        ContrastSlider_2Label           matlab.ui.control.Label
        ImageContrastSlider             matlab.ui.control.Slider
        BrightnessSlider_2Label         matlab.ui.control.Label
        ImageBrightnessSlider           matlab.ui.control.Slider
        ClickDPPointsButton             matlab.ui.control.Button
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        IntegrationTimemsEditField      matlab.ui.control.NumericEditField
        BlackLevelEditFieldLabel        matlab.ui.control.Label
        BlackLevelEditField             matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        CollectDPsButton                matlab.ui.control.Button
        CreateMaskTab                   matlab.ui.container.Tab
        DPImagePanel                    matlab.ui.container.Panel
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        openDPBGButton                  matlab.ui.control.Button
        DPBGFilenameLabel               matlab.ui.control.Label
        textDPBGfilenameField           matlab.ui.control.EditField
        openDP                          matlab.ui.control.Button
        DPFilenameLabel                 matlab.ui.control.Label
        textDPfilenameField             matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        ClickPointsTab                  matlab.ui.container.Tab
        ClickSpotsButton                matlab.ui.control.Button
        MaskRadiusradLabel              matlab.ui.control.Label
        MaskRadiusmRadClickSpotsEditField  matlab.ui.control.NumericEditField
        InvertClickSpotsCheckBox        matlab.ui.control.CheckBox
        ClickPointsMaskCheckBox         matlab.ui.control.CheckBox
        AnnularTab                      matlab.ui.container.Tab
        InnerRadiusradLabel             matlab.ui.control.Label
        InnerRadiusmRadAnnularEditField  matlab.ui.control.NumericEditField
        OuterRadiusradLabel             matlab.ui.control.Label
        OuterRadiusmRadAnnularEditField  matlab.ui.control.NumericEditField
        InvertAnnularCheckBox           matlab.ui.control.CheckBox
        AnnularMaskCheckBox             matlab.ui.control.CheckBox
        LatticeTab                      matlab.ui.container.Tab
        InvertLatticeCheckBox           matlab.ui.control.CheckBox
        MaskRadiusradLabel_2            matlab.ui.control.Label
        MaskRadiusmRadLatticeEditField  matlab.ui.control.NumericEditField
        g11nmEditFieldLabel             matlab.ui.control.Label
        g1invnmEditField                matlab.ui.control.NumericEditField
        g1degEditFieldLabel             matlab.ui.control.Label
        g1degEditField                  matlab.ui.control.NumericEditField
        g21nmEditFieldLabel             matlab.ui.control.Label
        g2invnmEditField                matlab.ui.control.NumericEditField
        g2degEditFieldLabel             matlab.ui.control.Label
        g2degEditField                  matlab.ui.control.NumericEditField
        LatticeMaskCheckBox             matlab.ui.control.CheckBox
        OptionsTab                      matlab.ui.container.Tab
        PatternCenterXEditFieldLabel    matlab.ui.control.Label
        PatternCenterXEditField         matlab.ui.control.NumericEditField
        PatternCenterYEditFieldLabel    matlab.ui.control.Label
        PatternCenterYEditField         matlab.ui.control.NumericEditField
        InvertoutofROImaskvalueCheckBox  matlab.ui.control.CheckBox
        DrawMaskButton                  matlab.ui.control.Button
        MaskFilenamedontincludebmpEditFieldLabel  matlab.ui.control.Label
        MaskFilenamedontincludebmpEditField  matlab.ui.control.EditField
        generateMaskButton              matlab.ui.control.Button
        SampleSEMInfoPanel              matlab.ui.container.Panel
        UseCurrentWDButton              matlab.ui.control.Button
        EHTkVEditFieldLabel             matlab.ui.control.Label
        EHTkVEditField                  matlab.ui.control.NumericEditField
        YAGWDmmEditFieldLabel           matlab.ui.control.Label
        YAGWDmmEditField                matlab.ui.control.NumericEditField
        SampleWDmmEditFieldLabel        matlab.ui.control.Label
        SampleWDmmEditField             matlab.ui.control.NumericEditField
        LoadCalibrationButton           matlab.ui.control.Button
        CalibrationTextArea             matlab.ui.control.TextArea
        CalibrationTextAreaLabel        matlab.ui.control.Label
        SEMCalibFactorEditFieldLabel    matlab.ui.control.Label
        SEMCalibFactorEditField         matlab.ui.control.NumericEditField
        RemCon32Panel                   matlab.ui.container.Panel
        RemConstateButton               matlab.ui.control.StateButton
        VersionResponseEditFieldLabel   matlab.ui.control.Label
        VersionResponseEditField        matlab.ui.control.EditField
        ChangeWDButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        
        dp_filename 
        dpbg_filename 
        
        % This is the background subtracted DP without bright/contrast
        dp
        im
        
        spots
        collectdpspots
        latticePoints
        
        x
        y
        
        % Note: each of these 2D arrays is ~70 MB of memory for 64 bit numbers
        X
        Y
        pointsMask
        annularMask
        latticeMask
        fullMask
               
        dpFigure
        imageFigure
        
        imgMap
      
        leo;  
        dmd;
        
    end

    methods (Access = private)

        
    end

    methods (Access = public)
    
        
        function results = updateMask(app)
            
            % Update Full Mask
            app.fullMask.initializeMask(ImageTypes.YAG,1);
                        
            % Update Center
            X0 = app.PatternCenterXEditField.Value;
            Y0 = app.PatternCenterYEditField.Value;
            
            CL = app.YAGWDmmEditField.Value-app.SampleWDmmEditField.Value;
            
            if(app.AnnularMaskCheckBox.Value)
                % Update annulus mask
                umRadInner = 1000*CL*tan(app.InnerRadiusmRadAnnularEditField.Value);
                umRadOuter = 1000*CL*tan(app.OuterRadiusmRadAnnularEditField.Value);
                        
                app.annularMask.makeAnnularMask([X0 Y0],umRadInner,umRadOuter,app.InvertAnnularCheckBox.Value);
                
                app.fullMask.img = app.annularMask.img.*app.fullMask.img;
            end
            
            if(app.ClickPointsMaskCheckBox.Value)
                % Update click points mask
                umRad = 1000*CL*tan(app.MaskRadiusmRadClickSpotsEditField.Value);
                
                app.pointsMask.makeSpotsMask(app.spots,umRad,app.InvertClickSpotsCheckBox.Value);                
                app.fullMask.img = app.pointsMask.img.*app.fullMask.img;
            end
            
            if(app.LatticeMaskCheckBox.Value) 
                                
                % Update lattice mask   
                N = 3;
                rot = @(theta_deg) [cos(theta_deg*pi/180) sin(theta_deg*pi/180)
                                    -sin(theta_deg*pi/180) cos(theta_deg*pi/180)];
                
                g1 = [app.g1invnmEditField.Value*1e9 0]'; % inverse m
                g1 = rot(app.g1degEditField.Value)*g1;
                g2 = [app.g2invnmEditField.Value*1e9 0]'; % inverse m
                g2 = rot(app.g2degEditField.Value)*g2;
                    
                app.latticePoints = zeros(2,(2*N+1)^2);
                idx = 0;
                for g1_idx=-N:N
                    for g2_idx=-N:N
                        idx = idx+1;
                        g = g1_idx*g1+g2_idx*g2;
                        len = norm(g);
                        polar_theta = atan2(g(2),g(1));
                        scat_theta = 2*asin(getElectronWavelength(app.EHTkVEditField.Value*1000)*len/2);
                        umRad = 1000*CL*tan(scat_theta);
                        
                        app.latticePoints(1,idx) = X0 + cos(polar_theta)*umRad;
                        app.latticePoints(2,idx) = Y0 + sin(polar_theta)*umRad;
                    end 
                end
                
                umRad = 1000*CL*tan(app.MaskRadiusmRadLatticeEditField.Value);
                
                app.latticeMask.makeSpotsMask(app.latticePoints,umRad,app.InvertLatticeCheckBox.Value);                
                
                app.fullMask.img = app.latticeMask.img.*app.fullMask.img;
            end


        end
        
        function results = drawDP(app)
                    
                % Get figure
                figure(app.dpFigure)
                clf
                
                plot(app.dp,app.ContrastSlider.Value,app.BrightnessSlider.Value);
                hold on
                h = plot(app.fullMask,[0 1 0],0.25);
                
                xval = app.PatternCenterXEditField.Value;
                yval = app.PatternCenterYEditField.Value;

                plot(xval*[1 1],[min(min(app.fullMask.Y)) max(max(app.fullMask.Y))],'k-','Linewidth',1)
                plot([min(min(app.fullMask.X)) max(max(app.fullMask.X))],yval*[1 1],'k-','Linewidth',1)
        end
        
        function results = drawImage(app)                 
                % Get figure
                figure(app.imageFigure)
                clf
                plot(app.im,app.ImageContrastSlider.Value,app.ImageBrightnessSlider.Value);
                
                col = lines(1);
                hold on
                for i=1:size(app.collectdpspots,2)
                    plot(app.collectdpspots(1,i),app.collectdpspots(2,i),'+','MarkerEdgeColor',col,'MarkerSize',12)
                    text(app.collectdpspots(1,i)+25,app.collectdpspots(2,i)+25,num2str(i),'Color',col)
                end
                axis equal
                hold on
        end
        
        function results = updateImage(app)
            imfp = app.textImagefilenameField.Value;
            if(exist(imfp)==2)
                app.im = MonochromeImage(imfp); 
                drawImage(app);
            end
        end
        
        function results = updateDP(app)
            isDP = 0;
            isBG = 0;
            
            % Correct DP
            dpfp = app.textDPfilenameField.Value;
            if(exist(dpfp)==2)
                dp = MonochromeImage(dpfp);
                dp.imgType = ImageTypes.CMOS;
                isDP = 1;
            end        
            
            % Correct BGDP
            dpbgfp = app.textDPBGfilenameField.Value;
            if(exist(dpbgfp)==2)
                dpbg = MonochromeImage(dpbgfp);
                isBG = 1;
            end        
            
            if(isDP)
                if(isBG) 
                    dp.img = dp.img-dpbg.img;
                end

                app.dp = app.imgMap.doMapping(dp,ImageTypes.YAG);
                drawDP(app);
            end
        end
        
        function loadImageMapping(app,fn)
            
            X = load(fn);
            app.imgMap = X.img_mapping;
            
            sf = app.imgMap.fToString();
            sg = app.imgMap.gToString();
            
            app.CalibrationTextArea.Value = [sf sg];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.WorkingDirectoryEditField.Value = 'Z:\';
            
      
            app.dp_filename = [];
            
            app.dpbg_filename = [];
        
            app.im = [];
            app.dp = MonochromeImage(ImageTypes.YAG);
            
            app.collectdpspots = [];
            
            %                 
            %             fmat =[   -1.1994    9.2593
            %             9.3862    2.1312];
            %             fvec =   1.0e+04 *[   -0.3353
            %             -1.1013];
            %             gmat =[    0.6905    0.0245
            %             0.0357    0.6931];
            %             gvec =[ -214.7958
            %             13.0063];
            %         
            %             app.imgMap = ImageMapping(fmat,fvec,gmat,gvec,1);
            %       
            
            
            [app.X,app.Y] = meshgrid(app.x,app.y);
            
            
            app.pointsMask = MaskImage();
            app.pointsMask.initializeMask(ImageTypes.YAG);
            
            app.annularMask = MaskImage();
            app.annularMask.initializeMask(ImageTypes.YAG);
            
            app.latticeMask = MaskImage();
            app.latticeMask.initializeMask(ImageTypes.YAG);
            
            app.fullMask = MaskImage();
            app.fullMask.initializeMask(ImageTypes.YAG);
            
            app.dpFigure= figure(666)
            app.imageFigure= figure(777)
            
            app.loadImageMapping('Z:\IMAGEMAPPING.mat');
            
            
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            drawDP(app);
        end

        % Button pushed function: ChangeWDButton
        function ChangeWDButtonPushed(app, event)
             
            wd = uigetdir('Z:\','Select a new working directory');
            if(wd(end) ~= '\')
                wd = [wd '\'];
            end
            
            app.WorkingDirectoryEditField.Value = wd;
            
        end

        % Button pushed function: ClickDPPointsButton
        function ClickDPPointsButtonPushed(app, event)
            figure(app.imageFigure)
            app.collectdpspots = ginput';
            
            drawImage(app)
        end

        % Button pushed function: ClickSpotsButton
        function ClickSpotsButtonButtonPushed(app, event)
            figure(app.dpFigure)
            app.spots = ginput';
            
            updateMask(app)
            
        end

        % Button pushed function: CollectDPsButton
        function CollectDPsButtonPushed(app, event)
            
            times = [];
            
            app.dmd.writeUpMaskToDMD();
            
            cmos_args = CMOSArgs(round(app.IntegrationTimemsEditField.Value),...
            round(app.GainEditField.Value),...
            round(app.BlackLevelEditField.Value));
            
            cmos = CMOSController([],cmos_args,1);
            cmos.start();
            
            for i=1:size(app.collectdpspots,2)
                app.leo.cmdBeamBlankToggle(1);
                app.leo.cmdSpotMode(round(app.collectdpspots(1,i)),round(app.collectdpspots(2,i)))
                app.leo.cmdBeamBlankToggle(0);
                
                
                fname = sprintf('%s_DP_%.4d_%.4d_capture.png',...
                    app.textImagefilenameField.Value(1:end-4),...
                    round(app.collectdpspots(1,i)),...
                    round(app.collectdpspots(2,i)));
                
                cmos.saveCmosImage(fname);


                times = [times toc];
            
            end
            
            app.leo.cmdNormalMode();
            app.leo.cmdBeamBlankToggle(1);
            pause(0.25)
            fname = sprintf('%s_DP_bg_capture.png',...
                app.textImagefilenameField.Value(1:end-4));
            
            cmos.saveCmosImage(fname);


            cmos.stop();


        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            drawDP(app);
        end

        % Value changed function: DMDstateButton
        function DMDstateButtonValueChanged(app, event)
            value = app.DMDstateButton.Value;
            
            if(value)
                % start DMD process
                app.dmd = DMDController();
                app.dmd.start();
            else
                % stop DMD process
                app.dmd.stop();
            end
            
        end

        % Button pushed function: DrawMaskButton
        function DrawMaskButtonPushed(app, event)
            updateMask(app);
            drawDP(app);
        end

        % Value changed function: ImageBrightnessSlider
        function ImageBrightnessSliderValueChanged(app, event)
            value = app.ImageBrightnessSlider.Value;
           drawImage(app); 
        end

        % Value changed function: ImageContrastSlider
        function ImageContrastSliderValueChanged(app, event)
            value = app.ImageContrastSlider.Value;
            drawImage(app);
        end

        % Value changed function: InvertClickSpotsCheckBox
        function InvertClickSpotsCheckBoxValueChanged(app, event)
            value = app.InvertClickSpotsCheckBox.Value;
            updateMask(app);
        end

        % Value changed function: InvertoutofROImaskvalueCheckBox
        function InvertoutofROImaskvalueCheckBoxValueChanged(app, event)
            value = app.InvertoutofROImaskvalueCheckBox.Value;
            app.imgMap.extrap_val = double(value);
        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            
            [fname,pname,FilterIndex] = uigetfile('*.mat','Select ImageMapping object',app.WorkingDirectoryEditField.Value);
            fn = [pname fname];
            
            app.loadImageMapping(fn);
            
            
        end

        % Value changed function: RemConstateButton
        function RemConstateButtonValueChanged(app, event)
            value = app.RemConstateButton.Value;
            if(value)
                % start RemCon
                app.leo = RemCon32Microscope();
                app.leo.openConnection();
                app.VersionResponseEditField.Value  = app.leo.queryVersion();
            else
                % stop RemCon
                app.leo.closeAllSerialConnections();
            end
        end

        % Button pushed function: UseCurrentWDButton
        function UseCurrentWDButtonPushed(app, event)
            
            app.SampleWDmmEditField.Value = app.leo.queryWorkingDistance();
            
            
        end

        % Button pushed function: generateMaskButton
        function generateMaskButtonButtonPushed(app, event)
            
            mask_fn = [app.WorkingDirectoryEditField.Value app.MaskFilenamedontincludebmpEditField.Value '.bmp'];
            app.fullMask;
            
            mask = app.imgMap.doMapping(app.fullMask,ImageTypes.DMD);
            mask.invertMask();
            mask.saveImageAsBMP(mask_fn);
            app.WrittenMaskFilenameEditField.Value = mask_fn;
            
        end

        % Button pushed function: openDPBGButton
        function openDPBGButtonPushed(app, event)
            [fname,pname,FilterIndex] = uigetfile('*.png','Select raw DP',app.WorkingDirectoryEditField.Value);
            app.textDPBGfilenameField.Value = [pname fname];
            updateDP(app)
        end

        % Button pushed function: openDP
        function openDPButtonPushed(app, event)
            [fname,pname,FilterIndex] = uigetfile('*.png','Select raw DP',app.WorkingDirectoryEditField.Value);
            app.textDPfilenameField.Value = [pname fname];
            updateDP(app)
        end

        % Button pushed function: openImage
        function openImageButtonPushed(app, event)
            
            [fname,pname,FilterIndex] = uigetfile('*.tif','Select SEM image',app.WorkingDirectoryEditField.Value);
            app.textImagefilenameField.Value = [pname fname];
            updateImage(app)
        end

        % Button pushed function: pushMaskToDMDButton
        function pushMaskToDMDButtonButtonPushed(app, event)
            % Push mask to DMD
            app.dmd.writeFileToDMD(app.WrittenMaskFilenameEditField.Value);
            
            disp('The following was written to the DMD')
            app.WrittenMaskFilenameEditField.Value
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DMDControlPanel = uipanel(app.UIFigure);
            app.DMDControlPanel.Title = 'DMD Control';
            app.DMDstateButton = uibutton(app.DMDControlPanel, 'state');
            app.DMDstateButton.ValueChangedFcn = createCallbackFcn(app, @DMDstateButtonValueChanged, true);
            app.DMDstateButton.Text = 'DMD Start/Stop';
            app.pushMaskToDMDButton = uibutton(app.DMDControlPanel, 'push');
            app.pushMaskToDMDButton.ButtonPushedFcn = createCallbackFcn(app, @pushMaskToDMDButtonButtonPushed, true);
            app.pushMaskToDMDButton.Text = 'Push Mask to DMD';
            app.MaskFilenameLabel = uilabel(app.DMDControlPanel);
            app.MaskFilenameLabel.Text = 'Mask Filename';
            app.WrittenMaskFilenameEditField = uieditfield(app.DMDControlPanel, 'text');
            app.WorkingDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.WorkingDirectoryEditField.Editable = 'off';
            app.WorkingDirectoryEditField.Value = 'Z:\';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CollectDPTab = uitab(app.TabGroup2);
            app.CollectDPTab.Title = 'Collect DP';
            app.SEMImagePanel = uipanel(app.CollectDPTab);
            app.SEMImagePanel.Title = 'SEM Image';
            app.openImage = uibutton(app.SEMImagePanel, 'push');
            app.openImage.ButtonPushedFcn = createCallbackFcn(app, @openImageButtonPushed, true);
            app.openImage.Text = 'Open Image';
            app.ImageFilenameLabel = uilabel(app.SEMImagePanel);
            app.ImageFilenameLabel.Text = 'Image Filename';
            app.textImagefilenameField = uieditfield(app.SEMImagePanel, 'text');
            app.textImagefilenameField.Editable = 'off';
            app.ContrastSlider_2Label = uilabel(app.CollectDPTab);
            app.ContrastSlider_2Label.Text = 'Contrast';
            app.ImageContrastSlider = uislider(app.CollectDPTab);
            app.ImageContrastSlider.Limits = [1 10];
            app.ImageContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSliderValueChanged, true);
            app.ImageContrastSlider.Value = 1;
            app.BrightnessSlider_2Label = uilabel(app.CollectDPTab);
            app.BrightnessSlider_2Label.Text = 'Brightness';
            app.ImageBrightnessSlider = uislider(app.CollectDPTab);
            app.ImageBrightnessSlider.Limits = [0 1];
            app.ImageBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ImageBrightnessSliderValueChanged, true);
            app.ClickDPPointsButton = uibutton(app.CollectDPTab, 'push');
            app.ClickDPPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClickDPPointsButtonPushed, true);
            app.ClickDPPointsButton.Text = 'Click DP Points';
            app.IntegrationTimemsEditFieldLabel = uilabel(app.CollectDPTab);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.IntegrationTimemsEditField = uieditfield(app.CollectDPTab, 'numeric');
            app.IntegrationTimemsEditField.Limits = [10 10000];
            app.IntegrationTimemsEditField.Value = 1000;
            app.BlackLevelEditFieldLabel = uilabel(app.CollectDPTab);
            app.BlackLevelEditFieldLabel.Text = 'Black Level';
            app.BlackLevelEditField = uieditfield(app.CollectDPTab, 'numeric');
            app.BlackLevelEditField.Limits = [0 255];
            app.BlackLevelEditField.Value = 255;
            app.GainEditFieldLabel = uilabel(app.CollectDPTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.CollectDPTab, 'numeric');
            app.GainEditField.Limits = [0 100];
            app.GainEditField.Value = 30;
            app.CollectDPsButton = uibutton(app.CollectDPTab, 'push');
            app.CollectDPsButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDPsButtonPushed, true);
            app.CollectDPsButton.Text = 'Collect DP''s';
            app.CreateMaskTab = uitab(app.TabGroup2);
            app.CreateMaskTab.Title = 'Create Mask';
            app.DPImagePanel = uipanel(app.CreateMaskTab);
            app.DPImagePanel.Title = 'DP Image';
            app.ContrastSliderLabel = uilabel(app.DPImagePanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.DPImagePanel);
            app.ContrastSlider.Limits = [1 10];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 2;
            app.BrightnessSliderLabel = uilabel(app.DPImagePanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.DPImagePanel);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 0.2;
            app.openDPBGButton = uibutton(app.DPImagePanel, 'push');
            app.openDPBGButton.ButtonPushedFcn = createCallbackFcn(app, @openDPBGButtonPushed, true);
            app.openDPBGButton.Text = 'Open DP (background)';
            app.DPBGFilenameLabel = uilabel(app.DPImagePanel);
            app.DPBGFilenameLabel.Text = 'DP BG Filename';
            app.textDPBGfilenameField = uieditfield(app.DPImagePanel, 'text');
            app.textDPBGfilenameField.Editable = 'off';
            app.openDP = uibutton(app.DPImagePanel, 'push');
            app.openDP.ButtonPushedFcn = createCallbackFcn(app, @openDPButtonPushed, true);
            app.openDP.Text = 'Open DP';
            app.DPFilenameLabel = uilabel(app.DPImagePanel);
            app.DPFilenameLabel.Text = 'DP Filename';
            app.textDPfilenameField = uieditfield(app.DPImagePanel, 'text');
            app.textDPfilenameField.Editable = 'off';
            app.TabGroup = uitabgroup(app.CreateMaskTab);
            app.ClickPointsTab = uitab(app.TabGroup);
            app.ClickPointsTab.Title = 'Click Points';
            app.ClickSpotsButton = uibutton(app.ClickPointsTab, 'push');
            app.ClickSpotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClickSpotsButtonButtonPushed, true);
            app.ClickSpotsButton.Text = 'Click Spots';
            app.MaskRadiusradLabel = uilabel(app.ClickPointsTab);
            app.MaskRadiusradLabel.Text = 'Mask Radius (rad)';
            app.MaskRadiusmRadClickSpotsEditField = uieditfield(app.ClickPointsTab, 'numeric');
            app.MaskRadiusmRadClickSpotsEditField.Limits = [0.001 1];
            app.MaskRadiusmRadClickSpotsEditField.ValueDisplayFormat = '%.3f';
            app.MaskRadiusmRadClickSpotsEditField.Value = 0.003;
            app.InvertClickSpotsCheckBox = uicheckbox(app.ClickPointsTab);
            app.InvertClickSpotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertClickSpotsCheckBoxValueChanged, true);
            app.InvertClickSpotsCheckBox.Text = 'Invert';
            app.ClickPointsMaskCheckBox = uicheckbox(app.ClickPointsTab);
            app.ClickPointsMaskCheckBox.Text = 'Click Points Mask';
            app.AnnularTab = uitab(app.TabGroup);
            app.AnnularTab.Title = 'Annular';
            app.InnerRadiusradLabel = uilabel(app.AnnularTab);
            app.InnerRadiusradLabel.Text = 'Inner Radius (rad)';
            app.InnerRadiusmRadAnnularEditField = uieditfield(app.AnnularTab, 'numeric');
            app.InnerRadiusmRadAnnularEditField.Limits = [0 1];
            app.InnerRadiusmRadAnnularEditField.ValueDisplayFormat = '%.3f';
            app.InnerRadiusmRadAnnularEditField.Value = 0.1;
            app.OuterRadiusradLabel = uilabel(app.AnnularTab);
            app.OuterRadiusradLabel.Text = 'Outer Radius (rad)';
            app.OuterRadiusmRadAnnularEditField = uieditfield(app.AnnularTab, 'numeric');
            app.OuterRadiusmRadAnnularEditField.Limits = [0 1];
            app.OuterRadiusmRadAnnularEditField.ValueDisplayFormat = '%.3f';
            app.OuterRadiusmRadAnnularEditField.Value = 0.2;
            app.InvertAnnularCheckBox = uicheckbox(app.AnnularTab);
            app.InvertAnnularCheckBox.Text = 'Invert';
            app.AnnularMaskCheckBox = uicheckbox(app.AnnularTab);
            app.AnnularMaskCheckBox.Text = 'Annular Mask';
            app.LatticeTab = uitab(app.TabGroup);
            app.LatticeTab.Title = 'Lattice';
            app.InvertLatticeCheckBox = uicheckbox(app.LatticeTab);
            app.InvertLatticeCheckBox.Text = 'Invert';
            app.MaskRadiusradLabel_2 = uilabel(app.LatticeTab);
            app.MaskRadiusradLabel_2.Text = 'Mask Radius (rad)';
            app.MaskRadiusmRadLatticeEditField = uieditfield(app.LatticeTab, 'numeric');
            app.MaskRadiusmRadLatticeEditField.Limits = [0.001 0.3];
            app.MaskRadiusmRadLatticeEditField.ValueDisplayFormat = '%.3f';
            app.MaskRadiusmRadLatticeEditField.Value = 0.003;
            app.g11nmEditFieldLabel = uilabel(app.LatticeTab);
            app.g11nmEditFieldLabel.Text = 'g1 (1/nm)';
            app.g1invnmEditField = uieditfield(app.LatticeTab, 'numeric');
            app.g1invnmEditField.Limits = [0 100];
            app.g1invnmEditField.ValueDisplayFormat = '%.3f';
            app.g1invnmEditField.Value = 4.914;
            app.g1degEditFieldLabel = uilabel(app.LatticeTab);
            app.g1degEditFieldLabel.Text = 'g1 (deg)';
            app.g1degEditField = uieditfield(app.LatticeTab, 'numeric');
            app.g1degEditField.Limits = [0 359.9];
            app.g1degEditField.ValueDisplayFormat = '%.1f';
            app.g21nmEditFieldLabel = uilabel(app.LatticeTab);
            app.g21nmEditFieldLabel.Text = 'g2 (1/nm)';
            app.g2invnmEditField = uieditfield(app.LatticeTab, 'numeric');
            app.g2invnmEditField.Limits = [0 100];
            app.g2invnmEditField.ValueDisplayFormat = '%.3f';
            app.g2invnmEditField.Value = 4.914;
            app.g2degEditFieldLabel = uilabel(app.LatticeTab);
            app.g2degEditFieldLabel.Text = 'g2 (deg)';
            app.g2degEditField = uieditfield(app.LatticeTab, 'numeric');
            app.g2degEditField.Limits = [0 359.9];
            app.g2degEditField.ValueDisplayFormat = '%.1f';
            app.g2degEditField.Value = 90;
            app.LatticeMaskCheckBox = uicheckbox(app.LatticeTab);
            app.LatticeMaskCheckBox.Text = 'Lattice Mask';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.PatternCenterXEditFieldLabel = uilabel(app.OptionsTab);
            app.PatternCenterXEditFieldLabel.Text = 'Pattern Center X';
            app.PatternCenterXEditField = uieditfield(app.OptionsTab, 'numeric');
            app.PatternCenterYEditFieldLabel = uilabel(app.OptionsTab);
            app.PatternCenterYEditFieldLabel.Text = 'Pattern Center Y';
            app.PatternCenterYEditField = uieditfield(app.OptionsTab, 'numeric');
            app.InvertoutofROImaskvalueCheckBox = uicheckbox(app.OptionsTab);
            app.InvertoutofROImaskvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertoutofROImaskvalueCheckBoxValueChanged, true);
            app.InvertoutofROImaskvalueCheckBox.Text = 'Invert out of ROI mask value';
            app.DrawMaskButton = uibutton(app.CreateMaskTab, 'push');
            app.DrawMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMaskButtonPushed, true);
            app.DrawMaskButton.Text = 'Draw Mask';
            app.MaskFilenamedontincludebmpEditFieldLabel = uilabel(app.CreateMaskTab);
            app.MaskFilenamedontincludebmpEditFieldLabel.Text = 'Mask Filename (don''t include .bmp)';
            app.MaskFilenamedontincludebmpEditField = uieditfield(app.CreateMaskTab, 'text');
            app.generateMaskButton = uibutton(app.CreateMaskTab, 'push');
            app.generateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @generateMaskButtonButtonPushed, true);
            app.generateMaskButton.Text = 'Generate Mask File';
            app.SampleSEMInfoPanel = uipanel(app.UIFigure);
            app.SampleSEMInfoPanel.Title = 'Sample/SEM Info';
            app.UseCurrentWDButton = uibutton(app.SampleSEMInfoPanel, 'push');
            app.UseCurrentWDButton.ButtonPushedFcn = createCallbackFcn(app, @UseCurrentWDButtonPushed, true);
            app.UseCurrentWDButton.Text = 'Use Current WD';
            app.EHTkVEditFieldLabel = uilabel(app.SampleSEMInfoPanel);
            app.EHTkVEditFieldLabel.Text = 'EHT (kV)';
            app.EHTkVEditField = uieditfield(app.SampleSEMInfoPanel, 'numeric');
            app.EHTkVEditField.Value = 30;
            app.YAGWDmmEditFieldLabel = uilabel(app.SampleSEMInfoPanel);
            app.YAGWDmmEditFieldLabel.Text = 'YAG WD (mm)';
            app.YAGWDmmEditField = uieditfield(app.SampleSEMInfoPanel, 'numeric');
            app.YAGWDmmEditField.Value = 24.8;
            app.SampleWDmmEditFieldLabel = uilabel(app.SampleSEMInfoPanel);
            app.SampleWDmmEditFieldLabel.Text = 'Sample WD (mm)';
            app.SampleWDmmEditField = uieditfield(app.SampleSEMInfoPanel, 'numeric');
            app.SampleWDmmEditField.Value = 12.5;
            app.LoadCalibrationButton = uibutton(app.SampleSEMInfoPanel, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.CalibrationTextArea = uitextarea(app.SampleSEMInfoPanel);
            app.CalibrationTextAreaLabel = uilabel(app.SampleSEMInfoPanel);
            app.CalibrationTextAreaLabel.Text = 'Calibration';
            app.SEMCalibFactorEditFieldLabel = uilabel(app.SampleSEMInfoPanel);
            app.SEMCalibFactorEditFieldLabel.Text = 'SEM Calib Factor';
            app.SEMCalibFactorEditField = uieditfield(app.SampleSEMInfoPanel, 'numeric');
            app.SEMCalibFactorEditField.Limits = [0.9 1.1];
            app.SEMCalibFactorEditField.ValueDisplayFormat = '%.2f';
            app.SEMCalibFactorEditField.Value = 1.05;
            app.RemCon32Panel = uipanel(app.UIFigure);
            app.RemCon32Panel.Title = 'RemCon32';
            app.RemConstateButton = uibutton(app.RemCon32Panel, 'state');
            app.RemConstateButton.ValueChangedFcn = createCallbackFcn(app, @RemConstateButtonValueChanged, true);
            app.RemConstateButton.Text = 'RemCon32 (Dis)Connect';
            app.VersionResponseEditFieldLabel = uilabel(app.RemCon32Panel);
            app.VersionResponseEditFieldLabel.Text = 'Version Response';
            app.VersionResponseEditField = uieditfield(app.RemCon32Panel, 'text');
            app.ChangeWDButton = uibutton(app.UIFigure, 'push');
            app.ChangeWDButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeWDButtonPushed, true);
            app.ChangeWDButton.Text = 'Change WD';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CollectDP_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end