classdef CorrectDP_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadImageMapButton            matlab.ui.control.Button
        SelectDPstoCorrectButton      matlab.ui.control.Button
        CorrectedFilenameSuffixEditFieldLabel  matlab.ui.control.Label
        CorrectedFilenameSuffixEditField  matlab.ui.control.EditField
        PerformCorrectionButton       matlab.ui.control.Button
        SelectBGDPButton              matlab.ui.control.Button
        BGDPEditFieldLabel            matlab.ui.control.Label
        BGDPEditField                 matlab.ui.control.EditField
        ImageMappingLabel             matlab.ui.control.Label
        CurrentImageMapEditField      matlab.ui.control.TextArea
        DPsLabel                      matlab.ui.control.Label
        DPsEditField                  matlab.ui.control.TextArea
        SubtractDPBackgroundCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        imgMap% Description
        filenames
        bgfilename
    end


    methods (Access = private)

        % Button pushed function: LoadImageMapButton
        function LoadImageMapButtonPushed(app, event)
            [fname,pname,FilterIndex] = uigetfile('*.mat','Select ImageMapping object');
            fn = [pname fname];
            
            X = load(fn);
            app.imgMap = X.img_mapping;
            
            sf = app.imgMap.fToString();
            sg = app.imgMap.gToString();
            
            app.CurrentImageMapEditField.Value = [sf sg];
            
        end

        % Button pushed function: SelectDPstoCorrectButton
        function SelectDPstoCorrectButtonPushed(app, event)
            [fnames,pnames] = uigetfile('*.png','Select Files to Correct','MultiSelect', 'on');
            out = [];
            for i=1:length(fnames)
                fnames{i} = [pnames fnames{i}];
            end
            app.filenames = fnames;
            app.DPsEditField.Value = fnames;
        end

        % Button pushed function: PerformCorrectionButton
        function PerformCorrectionButtonPushed(app, event)
            if(app.SubtractDPBackgroundCheckBox.Value)
                bg = MonochromeImage(app.bgfilename);
            end
            
            for i=1:length(app.filenames)
                fn = app.filenames{i};
                img = MonochromeImage(fn);
                img.imgType = ImageMapping.CMOS_IMAGE;
                if(app.SubtractDPBackgroundCheckBox.Value)
                    img.img = img.img-bg.img;
                end
                corr_img = img_mapping.doMapping(img,ImageMapping.YAG_IMAGE);
                corr_img.saveImageAsPNG([fn(1:end-4) app.CorrectedFilenameSuffixEditField.Value]);
            end
            
        end

        % Button pushed function: SelectBGDPButton
        function SelectBGDPButtonPushed(app, event)
            [fname,pname] = uigetfile('*.png','Select Files to Correct','MultiSelect', 'off');
            app.bgfilename = [pname fname];
            app.BGDPEditField.Value = app.bgfilename;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LoadImageMapButton = uibutton(app.UIFigure, 'push');
            app.LoadImageMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageMapButtonPushed, true);
            app.LoadImageMapButton.Text = 'Load ImageMap';
            app.SelectDPstoCorrectButton = uibutton(app.UIFigure, 'push');
            app.SelectDPstoCorrectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDPstoCorrectButtonPushed, true);
            app.SelectDPstoCorrectButton.Text = 'Select DP''s to Correct';
            app.CorrectedFilenameSuffixEditFieldLabel = uilabel(app.UIFigure);
            app.CorrectedFilenameSuffixEditFieldLabel.Text = 'Corrected Filename Suffix';
            app.CorrectedFilenameSuffixEditField = uieditfield(app.UIFigure, 'text');
            app.CorrectedFilenameSuffixEditField.Value = '_corrected';
            app.PerformCorrectionButton = uibutton(app.UIFigure, 'push');
            app.PerformCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PerformCorrectionButtonPushed, true);
            app.PerformCorrectionButton.Text = 'Perform Correction';
            app.SelectBGDPButton = uibutton(app.UIFigure, 'push');
            app.SelectBGDPButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBGDPButtonPushed, true);
            app.SelectBGDPButton.Text = 'Select BG DP';
            app.BGDPEditFieldLabel = uilabel(app.UIFigure);
            app.BGDPEditFieldLabel.Text = 'BG DP';
            app.BGDPEditField = uieditfield(app.UIFigure, 'text');
            app.ImageMappingLabel = uilabel(app.UIFigure);
            app.ImageMappingLabel.Text = 'Image Mapping';
            app.CurrentImageMapEditField = uitextarea(app.UIFigure);
            app.DPsLabel = uilabel(app.UIFigure);
            app.DPsLabel.Text = 'DP''s';
            app.DPsEditField = uitextarea(app.UIFigure);
            app.SubtractDPBackgroundCheckBox = uicheckbox(app.UIFigure);
            app.SubtractDPBackgroundCheckBox.Text = 'Subtract DP Background';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CorrectDP_v1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end