classdef SpotMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        openDP                      matlab.ui.control.Button
        DPFilenameLabel             matlab.ui.control.Label
        textDPfilename              matlab.ui.control.EditField
        takeSpotPoints              matlab.ui.control.Button
        MaskRadiusSliderLabel       matlab.ui.control.Label
        MaskRadiusSlider            matlab.ui.control.Slider
        generateMask                matlab.ui.control.Button
        DMDstateButton              matlab.ui.control.StateButton
        pushMaskToDMD               matlab.ui.control.Button
        MaskFilenameEditFieldLabel  matlab.ui.control.Label
        MaskFilenameEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        DP_WORKING_DIR = 'C:\Users\bwc\Documents\MATLAB\data\' % Description
        dp_filename = '';
        corrected_dp_filename = '';
        spots = [];
        
        imageFigure = figure(666);
        
        im = [];
        
        fmat =[  -0.1623    3.3449
            3.3356    0.4468]
        % 
        % 
        fvec = 1e3*[  -1.3176
           -2.2366]
        
        gmat =[ 0.6952    0.0430
            0.0178    0.6948]
        
        gvec =[ -15.4544
          -84.5319]

    end

    methods (Access = private)


    end

    methods (Access = public)
    
        function results = drawMask(app)
            drawImage(app)
            rad = linspace(0,2*pi,2^4);
            y = app.MaskRadiusSlider.Value*sin(rad);
            x = app.MaskRadiusSlider.Value*cos(rad);
            for i=1:size(app.spots,1)
                plot(x+app.spots(i,1),y+app.spots(i,2),'-r')
            end
        end
        
        function results = drawImage(app)
                figure(app.imageFigure)
                clf
                
                image(app.im);
                colormap('gray')
                caxis([0 255])
                hold on
        end
        
        function results = updateDP(app)
            fullpath = app.textDPfilename.Value;
            if(exist(fullpath)==2)
                app.corrected_dp_filename = correctImage(fullpath,app.fmat,app.fvec);
                close(gcf())
                rawim = imread(app.corrected_dp_filename);
%                 im = zeros([size(rawim) 3]);
%                 im(:,:,1) = rawim;
%                 im(:,:,2) = rawim;
%                 im(:,:,3) = rawim;
                app.im = rawim;
                drawImage(app);
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: openDP
        function openDPButtonPushed(app, event)
            [fname,pname,FilterIndex] = uigetfile('*.png','Select raw DP',app.DP_WORKING_DIR);
            app.textDPfilename.Value = [pname fname];
            updateDP(app)
        end

        % Button pushed function: takeSpotPoints
        function takeSpotPointsButtonPushed(app, event)
            figure(app.imageFigure)
            app.spots = ginput;
            
            drawMask(app)
            
        end

        % Button pushed function: generateMask
        function generateMaskButtonPushed(app, event)
            % Make the mask here and save it
            
            
        end

        % Value changed function: DMDstateButton
        function DMDstateButtonValueChanged(app, event)
            value = app.DMDstateButton.Value;
            
            if(value)
                % start DMD process
                
            else
                % stop DMD process
                
            end
            
        end

        % Button pushed function: pushMaskToDMD
        function pushMaskToDMDButtonPushed(app, event)
            % Push mask to DMD
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.openDP = uibutton(app.UIFigure, 'push');
            app.openDP.ButtonPushedFcn = createCallbackFcn(app, @openDPButtonPushed, true);
            app.openDP.Text = 'Open DP';
            app.DPFilenameLabel = uilabel(app.UIFigure);
            app.DPFilenameLabel.Text = 'DP Filename';
            app.textDPfilename = uieditfield(app.UIFigure, 'text');
            app.takeSpotPoints = uibutton(app.UIFigure, 'push');
            app.takeSpotPoints.ButtonPushedFcn = createCallbackFcn(app, @takeSpotPointsButtonPushed, true);
            app.takeSpotPoints.Text = 'TakeSpotPoints';
            app.MaskRadiusSliderLabel = uilabel(app.UIFigure);
            app.MaskRadiusSliderLabel.Text = 'MaskRadius';
            app.MaskRadiusSlider = uislider(app.UIFigure);
            app.MaskRadiusSlider.Limits = [6 100];
            app.MaskRadiusSlider.Value = 14;
            app.generateMask = uibutton(app.UIFigure, 'push');
            app.generateMask.ButtonPushedFcn = createCallbackFcn(app, @generateMaskButtonPushed, true);
            app.generateMask.Text = 'Generate Mask';
            app.DMDstateButton = uibutton(app.UIFigure, 'state');
            app.DMDstateButton.ValueChangedFcn = createCallbackFcn(app, @DMDstateButtonValueChanged, true);
            app.DMDstateButton.Text = 'DMD Start/Stop';
            app.pushMaskToDMD = uibutton(app.UIFigure, 'push');
            app.pushMaskToDMD.ButtonPushedFcn = createCallbackFcn(app, @pushMaskToDMDButtonPushed, true);
            app.pushMaskToDMD.Text = 'Push Mask to DMD';
            app.MaskFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.MaskFilenameEditFieldLabel.Text = 'Mask Filename';
            app.MaskFilenameEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpotMap()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end